classdef PG_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        UIAxes                  matlab.ui.control.UIAxes           % Title
        profileNameLabel        matlab.ui.control.Label            % GraphT...
        TabGroup                matlab.ui.container.TabGroup       % Techni...
        technicalTab            matlab.ui.container.Tab            % Techni...
        dropDownLabel           matlab.ui.control.Label            % Select...
        dropDown                matlab.ui.control.DropDown         % Option...
        descriptionPanel        matlab.ui.container.Panel          % Load P...
        desc_ProfileNameLabel   matlab.ui.control.Label            % Profil...
        descProfileSrcHyperlink matlab.ui.control.Label            % Link t...
        economicTab             matlab.ui.container.Tab            % Econom...
        ButtonGroup             matlab.ui.container.ButtonGroup    % Cost Type
        twoPartCostButton       matlab.ui.control.RadioButton      % Two-Part
        threePartCostButton     matlab.ui.control.RadioButton      % Three-...
        perKWLabel              matlab.ui.control.Label            % Per kW...
        perKWCostInput          matlab.ui.control.NumericEditField % [0 Inf]
        perKWhLabel             matlab.ui.control.Label            % Per kW...
        perKWhCostInput         matlab.ui.control.NumericEditField % [-Inf ...
        fixedCostLabel          matlab.ui.control.Label            % Fixed ...
        fixedCostInput          matlab.ui.control.NumericEditField % [-Inf ...
        semiFixedCostLabel      matlab.ui.control.Label            % Semi F...
        semiFixedCostInput      matlab.ui.control.NumericEditField % [0 Inf]
        variableCostLabel       matlab.ui.control.Label            % Variab...
        variableCostInput       matlab.ui.control.NumericEditField % [0 Inf]
        descriptionTextArea     matlab.ui.control.TextArea        
        LabelNumericEditField   matlab.ui.control.Label            % Ideal ...
        targetPUCostField       matlab.ui.control.NumericEditField % [0 9999]
        actualPUCostLabel       matlab.ui.control.Label            % Overal...
        actualPUCostField       matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField2  matlab.ui.control.Label            % Peak L...
        peakLoadField           matlab.ui.control.NumericEditField % [-Inf ...
        baseLoadLabel           matlab.ui.control.Label            % Base L...
        baseLoadField           matlab.ui.control.NumericEditField % [-Inf ...
        unitsLabel              matlab.ui.control.Label            % Units ...
        unitsField              matlab.ui.control.NumericEditField % [-Inf ...
        actualLoadFactorLabel   matlab.ui.control.Label            % Actual...
        actualLoadFactorField   matlab.ui.control.NumericEditField % [-Inf ...
        avgLoadLabel            matlab.ui.control.Label            % Avg Lo...
        avgLoadField            matlab.ui.control.NumericEditField % [0 Inf]
        recommendationsPanel    matlab.ui.container.Panel          % Recomm...
        idealLoadFactorLabel    matlab.ui.control.Label            % Target...
        targetLoadFactorField   matlab.ui.control.NumericEditField % [0 1]
        Button                  matlab.ui.control.Button           % Calculate
    end


    properties (Access = private)
        loadProfileData;         % MATLAB table
        loadProfileNames;        % Cell array of profile names
    end

    methods (Access = private)
        
        function updateLoadProfileGraph(app, profileName)
            plot(app.UIAxes, app.loadProfileData.(profileName));
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Load (kW)';
            app.UIAxes.Title.Visible = 'off';
            app.profileNameLabel.Text = profileName;
        end 
        
        function updateTechnicalMetrics(app, profileName)
            %    Computing the required metrics
            peakLoad = max(app.loadProfileData.(profileName));
            baseLoad = min(app.loadProfileData.(profileName));
            unitsConsumed = trapz(app.loadProfileData.(profileName));
            averageLoad = unitsConsumed / (peakLoad * 24.0);
            targetLoadFactor = app.targetLoadFactorField.Value;
            actualLoadFactor = averageLoad / peakLoad; 
            
            %    Updating data
            app.peakLoadField.Value = peakLoad;
            app.baseLoadField.Value = baseLoad;
            app.avgLoadField.Value = averageLoad;
            app.unitsField.Value = unitsConsumed;
            app.actualLoadFactorField.Value = actualLoadFactor; 
            
            %    Indicate whether LF above or below desired level
            if (actualLoadFactor >= targetLoadFactor)
                app.actualLoadFactorField.BackgroundColor = [0 1 0];
            else 
                app.actualLoadFactorField.BackgroundColor = [1 0 0];
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read the load profiles as a table with column headers specifying profile name
            app.loadProfileData = readtable('./all-load-profiles.xlsx');
            
            % Extract names of load profiles from table
            app.loadProfileNames = app.loadProfileData.Properties.VariableNames;
            
            % Populate dropdown with names of load profiles
            app.dropDown.Items = app.loadProfileNames;
      
            % Plot first load profile by default on startup
            updateLoadProfileGraph(app, app.loadProfileNames{1});
            updateTechnicalMetrics(app, app.loadProfileNames{1});


        end

        % dropDown value changed function
        function dropDownValueChanged(app, event)
            % Use name of new load profile to plot data
            updateLoadProfileGraph(app, event.Value);
            
            % Update the technical metrics for this load profile
            updateTechnicalMetrics(app, event.Value);
            
            % Update economic metrics for this load 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.profileNameLabel = uilabel(app.UIFigure);
            app.profileNameLabel.Text = 'GraphTitle';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.technicalTab = uitab(app.TabGroup);
            app.technicalTab.Units = 'pixels';
            app.technicalTab.Title = 'Technical Analysis';
            app.LabelNumericEditField2 = uilabel(app.technicalTab);
            app.LabelNumericEditField2.Text = 'Peak Load (kW)';
            app.peakLoadField = uieditfield(app.technicalTab, 'numeric');
            app.peakLoadField.ValueDisplayFormat = '%5.2f';
            app.peakLoadField.Editable = 'off';
            app.baseLoadLabel = uilabel(app.technicalTab);
            app.baseLoadLabel.Text = 'Base Load (kW)';
            app.baseLoadField = uieditfield(app.technicalTab, 'numeric');
            app.baseLoadField.ValueDisplayFormat = '%5.2f';
            app.baseLoadField.Editable = 'off';
            app.unitsLabel = uilabel(app.technicalTab);
            app.unitsLabel.Text = 'Units (kWh)';
            app.unitsField = uieditfield(app.technicalTab, 'numeric');
            app.unitsField.ValueDisplayFormat = '%5.2f';
            app.unitsField.Editable = 'off';
            app.actualLoadFactorLabel = uilabel(app.technicalTab);
            app.actualLoadFactorLabel.Text = 'Actual Load Factor';
            app.actualLoadFactorField = uieditfield(app.technicalTab, 'numeric');
            app.actualLoadFactorField.ValueDisplayFormat = '%5.2f';
            app.actualLoadFactorField.Editable = 'off';
            app.avgLoadLabel = uilabel(app.technicalTab);
            app.avgLoadLabel.Text = 'Avg Load (kW)';
            app.avgLoadField = uieditfield(app.technicalTab, 'numeric');
            app.avgLoadField.Limits = [0 Inf];
            app.avgLoadField.ValueDisplayFormat = '%5.2f';
            app.avgLoadField.Editable = 'off';
            app.idealLoadFactorLabel = uilabel(app.technicalTab);
            app.idealLoadFactorLabel.Text = 'Target Load Factor';
            app.targetLoadFactorField = uieditfield(app.technicalTab, 'numeric');
            app.targetLoadFactorField.Limits = [0 1];
            app.targetLoadFactorField.ValueDisplayFormat = '%5.2f';
            app.economicTab = uitab(app.TabGroup);
            app.economicTab.Units = 'pixels';
            app.economicTab.Title = 'Economic Analysis';
            app.ButtonGroup = uibuttongroup(app.economicTab);
            app.ButtonGroup.Title = 'Cost Type';
            app.ButtonGroup.Units = 'pixels';
            app.twoPartCostButton = uiradiobutton(app.ButtonGroup);
            app.twoPartCostButton.Text = 'Two-Part';
            app.twoPartCostButton.Value = true;
            app.threePartCostButton = uiradiobutton(app.ButtonGroup);
            app.threePartCostButton.Text = 'Three-Part';
            app.perKWLabel = uilabel(app.economicTab);
            app.perKWLabel.Text = 'Per kW (PKR)';
            app.perKWCostInput = uieditfield(app.economicTab, 'numeric');
            app.perKWCostInput.Limits = [0 Inf];
            app.perKWCostInput.ValueDisplayFormat = '%5.2f';
            app.perKWhLabel = uilabel(app.economicTab);
            app.perKWhLabel.Text = 'Per kWh (PKR)';
            app.perKWhCostInput = uieditfield(app.economicTab, 'numeric');
            app.perKWhCostInput.ValueDisplayFormat = '%5.2f';
            app.fixedCostLabel = uilabel(app.economicTab);
            app.fixedCostLabel.Enable = 'off';
            app.fixedCostLabel.Text = 'Fixed (PKR)';
            app.fixedCostInput = uieditfield(app.economicTab, 'numeric');
            app.fixedCostInput.ValueDisplayFormat = '%5.2f';
            app.fixedCostInput.Enable = 'off';
            app.semiFixedCostLabel = uilabel(app.economicTab);
            app.semiFixedCostLabel.Enable = 'off';
            app.semiFixedCostLabel.Text = 'Semi Fixed (PKR)';
            app.semiFixedCostInput = uieditfield(app.economicTab, 'numeric');
            app.semiFixedCostInput.Limits = [0 Inf];
            app.semiFixedCostInput.ValueDisplayFormat = '%5.2f';
            app.semiFixedCostInput.Enable = 'off';
            app.variableCostLabel = uilabel(app.economicTab);
            app.variableCostLabel.Enable = 'off';
            app.variableCostLabel.Text = 'Variable (PKR)';
            app.variableCostInput = uieditfield(app.economicTab, 'numeric');
            app.variableCostInput.Limits = [0 Inf];
            app.variableCostInput.ValueDisplayFormat = '%5.2f';
            app.variableCostInput.Enable = 'off';
            app.LabelNumericEditField = uilabel(app.economicTab);
            app.LabelNumericEditField.Text = 'Ideal Cost/kWh (PKR)';
            app.targetPUCostField = uieditfield(app.economicTab, 'numeric');
            app.targetPUCostField.Limits = [0 9999];
            app.targetPUCostField.ValueDisplayFormat = '%5.2f';
            app.actualPUCostLabel = uilabel(app.economicTab);
            app.actualPUCostLabel.Text = 'Overall Cost/kWh (PKR)';
            app.actualPUCostField = uieditfield(app.economicTab, 'numeric');
            app.actualPUCostField.Limits = [0 Inf];
            app.actualPUCostField.ValueDisplayFormat = '%5.2f';
            app.actualPUCostField.Editable = 'off';
            app.Button = uibutton(app.economicTab, 'push');
            app.Button.Text = 'Calculate';
            app.dropDownLabel = uilabel(app.UIFigure);
            app.dropDownLabel.Text = 'Select Load Profile';
            app.dropDown = uidropdown(app.UIFigure);
            app.dropDown.ValueChangedFcn = createCallbackFcn(app, @dropDownValueChanged, true);
            app.descriptionPanel = uipanel(app.UIFigure);
            app.descriptionPanel.Title = 'Load Profile Description';
            app.descriptionPanel.Units = 'pixels';
            app.desc_ProfileNameLabel = uilabel(app.descriptionPanel);
            app.desc_ProfileNameLabel.Text = 'Profile Name';
            app.descProfileSrcHyperlink = uilabel(app.descriptionPanel);
            app.descProfileSrcHyperlink.Text = 'Link to Source';
            app.descriptionTextArea = uitextarea(app.descriptionPanel);
            app.descriptionTextArea.Editable = 'off';
            app.recommendationsPanel = uipanel(app.UIFigure);
            app.recommendationsPanel.Title = 'Recommendations';
            app.recommendationsPanel.Units = 'pixels';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PG_App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end