classdef PG_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % Load Pr...
        UIAxes                 matlab.ui.control.UIAxes           % Title
        profileNameLabel       matlab.ui.control.Label            % GraphTitle
        TabGroup               matlab.ui.container.TabGroup       % Technic...
        technicalTab           matlab.ui.container.Tab            % Technic...
        LabelNumericEditField2 matlab.ui.control.Label            % Peak Lo...
        peakLoadField          matlab.ui.control.NumericEditField % [-Inf Inf]
        baseLoadLabel          matlab.ui.control.Label            % Base Lo...
        baseLoadField          matlab.ui.control.NumericEditField % [-Inf Inf]
        avgLoadLabel           matlab.ui.control.Label            % Avg Loa...
        avgLoadField           matlab.ui.control.NumericEditField % [0 Inf]
        unitsLabel             matlab.ui.control.Label            % Units (...
        unitsField             matlab.ui.control.NumericEditField % [-Inf Inf]
        actualLoadFactorLabel  matlab.ui.control.Label            % Actual ...
        actualLoadFactorField  matlab.ui.control.NumericEditField % [-Inf Inf]
        idealLoadFactorLabel   matlab.ui.control.Label            % Target ...
        targetLoadFactorField  matlab.ui.control.NumericEditField % [0 1]
        economicTab            matlab.ui.container.Tab            % Economi...
        CostTypeButtonGroup    matlab.ui.container.ButtonGroup    % Cost Type
        twoPartCostButton      matlab.ui.control.RadioButton      % Two-Part
        threePartCostButton    matlab.ui.control.RadioButton      % Three-Part
        perKWLabel             matlab.ui.control.Label            % Per kW ...
        perKWCostInput         matlab.ui.control.NumericEditField % [0 Inf]
        perKWhLabel            matlab.ui.control.Label            % Per kWh...
        perKWhCostInput        matlab.ui.control.NumericEditField % [-Inf Inf]
        fixedCostLabel         matlab.ui.control.Label            % Fixed (...
        fixedCostInput         matlab.ui.control.NumericEditField % [-Inf Inf]
        semiFixedCostLabel     matlab.ui.control.Label            % Semi Fi...
        semiFixedCostInput     matlab.ui.control.NumericEditField % [0 Inf]
        variableCostLabel      matlab.ui.control.Label            % Variabl...
        variableCostInput      matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField  matlab.ui.control.Label            % Ideal C...
        idealPUCostField       matlab.ui.control.NumericEditField % [0 9999]
        actualPUCostLabel      matlab.ui.control.Label            % Actual ...
        actualPUCostField      matlab.ui.control.NumericEditField % [0 Inf]
        economicUnitsLabel     matlab.ui.control.Label            % Units (...
        economicUnitsField     matlab.ui.control.NumericEditField % [-Inf Inf]
        economicSubmitBtn      matlab.ui.control.Button           % Update
        comparisonTab          matlab.ui.container.Tab            % Comparison
        comparisonButtonGroup  matlab.ui.container.ButtonGroup    % Compari...
        loadFactorComparison   matlab.ui.control.RadioButton      % Load Fa...
        costPerKWhComparison   matlab.ui.control.RadioButton      % Overall...
        comparisonSubmitBtn    matlab.ui.control.Button           % Update
        comparisonKWhLabel     matlab.ui.control.Label            % PKR/kWh
        comparisonKWhInput     matlab.ui.control.NumericEditField % [-Inf Inf]
        comparisonKWLabel      matlab.ui.control.Label            % PKR/kW
        comparisonKWInput      matlab.ui.control.NumericEditField % [-Inf Inf]
        bestLFNameLabel        matlab.ui.control.Label            % Load Name
        bestLFName             matlab.ui.control.EditField       
        bestLFValueLabel       matlab.ui.control.Label            % Best Lo...
        bestLFValue            matlab.ui.control.NumericEditField % [-Inf Inf]
        bestCostNameLabel      matlab.ui.control.Label            % Load Name
        bestCostName           matlab.ui.control.EditField       
        bestCostValueLabel     matlab.ui.control.Label            % Best Ov...
        bestCostValue          matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                  matlab.ui.control.Label            % Compari...
        Label2                 matlab.ui.control.Label            % Compari...
        dropDownLabel          matlab.ui.control.Label            % Select ...
        dropDown               matlab.ui.control.DropDown         % Option ...
        descriptionPanel       matlab.ui.container.Panel          % Load Pr...
        descProfileNameLabel   matlab.ui.control.Label            % Profile...
        descriptionTextArea    matlab.ui.control.TextArea        
        referenceButton        matlab.ui.control.Button           % Reference
        modelButton            matlab.ui.control.Button           % Model
        recommendationsPanel   matlab.ui.container.Panel          % Recomme...
        recommendationTextArea matlab.ui.control.TextArea        
    end

   %% PROPERTIES
    properties (Access = private)
        loadProfileData;         % MATLAB table
        loadProfileNames;        % Cell array of profile names
        timestamps;              % Datetimes for load profile data 
    end

    %%    PRIVATE FUNCTIONS
    methods (Access = private)
        %%    LOADING DATA 
        function readLoadProfileData(app) 
            % Read the load profiles as a table with column headers specifying profile name
            app.loadProfileData = readtable('./all-load-profiles.xlsx');
            
            %    Extract the time column
            app.timestamps = app.loadProfileData.('Time');
            
            %    Remove the data for the rest of the operations
            app.loadProfileData.('Time') = [];
            
            % Extract names of load profiles from table
            app.loadProfileNames = app.loadProfileData.Properties.VariableNames;
        end
        
        %%    STRING PROCESSING AND HELPER FUNCTIONS
        %%    Converting between variable names and strings
        function displayStr = variableToDisplayName(app, variableName)
            %    Replaces `_` in variable names with space for displaying in UI
            displayStr = strrep(variableName, '_', ' ');
        end
        
        function variableStr = displayNameToVariable(app, displayName)
            %    Replaces spaces in displayed variable names with _ for use in app backend
            variableStr = strrep(displayName, ' ', '_');
        end
        
        %%    Enabling and disabling inputs in economic tab
        function enableInputs(app, inputFields)
        %   Iterates through arg list of input fields and enables them for user input 
            for i = 1:length(inputFields)
                inputFields(i).Enable = 'on';
            end
        end
        
        function disableInputs(app, inputs)
        %   Iterates through arg list of input fields and disables them - user cannot enter input 
            for i = 1:length(inputs)
                inputs(i).Enable = 'off';
            end
        end
        %%    GRAPHS AND DRAWINGS
        %%    Updating LF field's background color based on target value
        function updateLoadFactorBackground(app)
            actualLoadFactor = app.actualLoadFactorField.Value;
            targetLoadFactor = app.targetLoadFactorField.Value;
               if (actualLoadFactor >= targetLoadFactor)
                   app.actualLoadFactorField.BackgroundColor = [0 1 0]; % Green
               else 
                   app.actualLoadFactorField.BackgroundColor = [1 0 0]; % Red
               end
        end
        
        %%    Updating PU Cost Field's background color based on target value
        function updatePUCostBackground(app)
            actualPUCost = app.actualPUCostField.Value;
            idealPUCost = app.idealPUCostField.Value;
            
            %    Inverse logic - actual must be below ideal
            if (actualPUCost > idealPUCost)
                app.actualPUCostField.BackgroundColor = [1 0 0];
            else
                app.actualPUCostField.BackgroundColor = [0 1 0];
            end
        end
        
        %%    Displaying single load profile
        function updateLoadProfileGraph(app, profileName)
            cla(app.UIAxes, 'reset');
            
            %    Need to manually update XTicks to represent dates
            app.UIAxes.XTickMode = 'manual';
            
            %    Make 25 ticks - one for each hour (25 hrs in data)
            app.UIAxes.XTick = (1:2:49);   
            
            %    Set each new tick to every second value in timestamps (hour)
            app.UIAxes.XTickLabel = app.timestamps(1:2:end);
            
            %    Rotate by 45 degrees for cleaner layout
            app.UIAxes.XTickLabelRotation = 45;
            
            %    Update graph title 
            app.profileNameLabel.Text = variableToDisplayName(app, profileName);
            
            %    Need to render grid
            grid(app.UIAxes, 'on');

            %   Updates UI Axes with line plot for load profile 
            plot(app.UIAxes, app.loadProfileData.(profileName));
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Load (kW)';
            app.UIAxes.Title.Visible = 'off';
        end 
        
        %%    Displaying load factor or per unit costs for all load profiles
        function generateComparisonPlot(app, values, metric)
            %    Reset ticks and other properties
            cla(app.UIAxes, 'reset');
            
            %    Remember the number of load profiles
            numProfiles = length(app.loadProfileNames);
            
            %    Update graph label from load profile name to Comparison Str
            app.profileNameLabel.Text = sprintf('Comparsion - %s', metric);
            
            %    Need to manually update XTicks to represent load profile names
            app.UIAxes.XTickMode = 'manual';            
            app.UIAxes.XLim = [0, (numProfiles + 1)];
            app.UIAxes.XLabel.String = 'Load Profile';
            app.UIAxes.XTick = (1 : numProfiles);   
            app.UIAxes.XTickLabel = variableToDisplayName(app, app.loadProfileNames);
            app.UIAxes.XTickLabelRotation = 45;
            grid(app.UIAxes);
            
            %   Display all values one by one            
            for i = 1:numProfiles
                %    Programmatically set color of line based on metric and value
                highestLoadFactor = strcmp('Load Factor', metric)...
                    && (values(i) == max(values));
                lowestCost = strcmp('Overall Cost/kWh', metric)...
                    && (values(i) == min(values));
                
                %    Green bar only drawn for highest LF/lowest PU cost
                if(highestLoadFactor || lowestCost)
                    c = 'g';
                else
                    c = 'b';
                end
                
                %    Make vertical line from x-axis to value
                %    Quick and dirty implementation for a bar graph
                plot(app.UIAxes, [i, i], [0, values(i)], 'Color', c);
                
                %    If first line, stop overwriting - draw on same axes
                if (i == 1)
                    hold(app.UIAxes, 'on');
                end
            end
            
            %    Update Y Labels accordingly
            app.UIAxes.YLabel.String = metric;
            
            %    Update y-axis limits
            range = max(values) - min(values);
            threshold = 0.1;
            app.UIAxes.YLim = [min(values) - threshold * range, 
                max(values) + threshold * range];
        end
        %%    METRICS AND PANELS
        %%    Update technical metrics based on user inputs and profile data
        function updateTechnicalMetrics(app, profileName)
            %    Computing the required metrics
            peakLoad = max(app.loadProfileData.(profileName));
            baseLoad = min(app.loadProfileData.(profileName));
            unitsConsumed = trapz(app.loadProfileData.(profileName));
            
            %    Implementation of `trapz` means must divide area by number of measurements
            %    In this case, 48 because measurements made every 1/2 hour over 24 hours
            averageLoad = unitsConsumed / (2 * 25.0); % MAGIC NUMBER - CONSIDER REPLACING
            actualLoadFactor = averageLoad / peakLoad; 
            
            %    Updating data in tab group's numeric fields
            app.peakLoadField.Value = peakLoad;
            app.baseLoadField.Value = baseLoad;
            app.avgLoadField.Value = averageLoad;
            app.unitsField.Value = unitsConsumed;
            app.actualLoadFactorField.Value = actualLoadFactor; 
            
            %    Indicate whether LF above or below desired level
            updateLoadFactorBackground(app);
        end

        %%    Update economic metrics based on user inputs and profile data
        function updateEconomicMetrics(app, profileName)
            %    Get number of units - display in field
            unitsConsumed = trapz(app.loadProfileData.(profileName));
            app.economicUnitsField.Value = unitsConsumed;
            
            %     Assume plant's installed capacity is 25% higher than max load
            installedCapacity = 1.25 * max(app.loadProfileData.(profileName));
            selectedCost = app.CostTypeButtonGroup.SelectedObject.Text;
            
            %    Based on selected cost, compute per unit cost
            perUnitCost = getPerUnitCost(app, installedCapacity, ...
                unitsConsumed, selectedCost);
            
            %    Update the perUnitCostLabel with this value
            app.actualPUCostField.Value = perUnitCost;    
            
            %    Update background color of this field
            updatePUCostBackground(app);
        end
        
        %%    Update metrics for comparison tab
        function updateComparisonMetrics(app)
            %    Compute load factors and costs for all profiles
            allCosts = computeAllPerUnitCosts(app);
            allLoadFactors = computeAllLoadFactors(app);
            
            %    Get value and index of best load factor and cost
            [maxLF, idxMaxLF] = max(allLoadFactors);
            [minCost, idxMinCost] = min(allCosts);
            
            %    Update best LF value and profile name in GUI
            app.bestLFName.Value = char(app.variableToDisplayName(...
                app.loadProfileNames(idxMaxLF)));
            app.bestLFValue.Value = maxLF;
            
            %    Update best cost value and profile name in GUI
            app.bestCostName.Value = char(app.variableToDisplayName(...
                app.loadProfileNames(idxMinCost)));
            app.bestCostValue.Value = minCost; 
            
            %    Updating the background colour of name fields - green to link to graph
            app.bestCostName.BackgroundColor = [0 1 0];
            app.bestLFName.BackgroundColor = [0 1 0];
        end

        %%    PANEL FUNCTIONS
        %%    Update description panel
        function updateDescriptionPanel(app, profileName)
        %    Updates the profiles name and description based on selected load profile
        %    Hyperlink to reference handled separately 'just-in-time' - see `referenceButtonPressed`
            app.descProfileNameLabel.Text = variableToDisplayName(app, profileName);
            app.descriptionTextArea.Value = importdata(strcat('./Descriptions/',...
                profileName, '.txt'));
            
            % If one of the two profiles with 3D models available
            if (strcmp(profileName, 'Zambia_Residential_Sector') || ...
                strcmp(profileName, 'Hybrid_Grid_System'))
                %    Enable the button
                app.modelButton.Enable = 'on';
            % Otherwise remains disabled
            else 
                app.modelButton.Enable = 'off';
            end
                
            % Close the file connection opened by importdata
            fclose('all');
        end

        %%    Update recommendations panel
        function updateRecommendationsPanel(app, profileName)
        %    Load recommendations from txt file with same name as load profile
            app.recommendationTextArea.Value = importdata(strcat('./Recommendations/',...
                profileName, '.txt'));
        end
        
        %%    ECONOMIC METRIC COMPUTATION
        %%    Get per unit costs for SINGLE profles
        function perUnitCost = getPerUnitCost(app, capacity, units, costType)
        %    Computes per-unit cost of load profile based on specified costType str
            %  costFlag = 0 => two part cost
            if (strcmp(costType, 'Two-Part'))
                perKW = app.perKWCostInput.Value;
                perKWh = app.perKWhCostInput.Value;
                
                perUnitCost = (capacity * perKW + units * perKWh) / units;
            else
                fixed = app.fixedCostInput.Value;
                semi_fixed = app.semiFixedCostInput.Value;
                variable = app.variableCostInput.Value; 
                
                perUnitCost = (fixed + semi_fixed * capacity + variable * units) / units;
            end
        end
        
        %%    COMPARISON METRIC COMPUTATION HELPERS
        %%    Get load factors for ALL profiles
        function allLoadFactors = computeAllLoadFactors(app)
            allLoadFactors = zeros(length(app.loadProfileNames), 1);
            for i = 1:length(app.loadProfileNames)
               units = trapz(app.loadProfileData.(app.loadProfileNames{i})); 
               peakLoad = max(app.loadProfileData.(app.loadProfileNames{i}));
               avgLoad = units / (25.0 * 2);    % MAGIC NUMBER 
               allLoadFactors(i) = avgLoad / peakLoad;
            end
        end
        
        %%    Get per kWh costs for ALL profiles
        function allCosts = computeAllPerUnitCosts(app)
            %    Create vector to store overall costs for all loads
            allCosts = zeros(length(app.loadProfileNames), 1);
            
            %    These costs will be used in calculations for all loads
            perKW = app.comparisonKWInput.Value;
            perKWh = app.comparisonKWhInput.Value;
            
            %    For each load, 
            for i = 1:length(app.loadProfileNames)
                %     Metrics from load data
                units = trapz(app.loadProfileData.(app.loadProfileNames{i}));
                capacity = 1.25 * max(app.loadProfileData.(app.loadProfileNames{i}));
                
                %    Compute cost, add to results vector
                allCosts(i) = (perKWh * units + perKW * capacity) / units;         
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read load profile data into MATLAB table 
            readLoadProfileData(app);
            
            % Populate dropdown with names of load profiles (underscores removed)
            app.dropDown.Items = variableToDisplayName(app, app.loadProfileNames);
      
            % Plot first load profile by default on startup
            updateLoadProfileGraph(app, app.loadProfileNames{1});
            
            % Update metrics and panels
            updateTechnicalMetrics(app, app.loadProfileNames{1});
            updateEconomicMetrics(app, app.loadProfileNames{1});
            updateDescriptionPanel(app, app.loadProfileNames{1});
            updateRecommendationsPanel(app, app.loadProfileNames{1});
            updateComparisonMetrics(app);
        end

        % dropDown value changed function
        function dropDownValueChanged(app, event)
            % Use name of new load profile to plot data
            updateLoadProfileGraph(app, displayNameToVariable(app, event.Value));
            
            % Update the technical metrics for this load profile
            updateTechnicalMetrics(app, displayNameToVariable(app, event.Value));
           
            % Update economic metrics for this load - 
            updateEconomicMetrics(app, displayNameToVariable(app, event.Value));
            
            % Load default description and recommendations content 
            updateDescriptionPanel(app, displayNameToVariable(app, event.Value));
            updateRecommendationsPanel(app, displayNameToVariable(app, event.Value));
        end

        % targetLoadFactorField value changed function
        function targetLoadFactorFieldValueChanged(app)
            % Will simply change the bg color of the load factor to show if it meets target
            updateLoadFactorBackground(app); 
        end

        % referenceButton button pushed function
        function referenceButtonPressed(app)
            % Get variable for active load profile
            profileName = displayNameToVariable(app, ...
                app.profileNameLabel.Text);
            
            % Build URL filepath
            filepath = strcat('./Links/', profileName, '.txt');
            
            % Extract URL from the file
            URL = importdata(filepath);
            
            %    Remember to close the file before proceeding
            fclose('all');
          
            %    Open with MATLAB's `webpage` command - must cast to char
            web(char(URL));
        end

        % CostTypeButtonGroup selection change function
        function costTypeChanged(app, event)
            %    Get a reference to the selected radio button
            selectedButton = app.CostTypeButtonGroup.SelectedObject;
            
            %    Making arrays of inputs that will need to be toggled
            twoPartInputs = [app.perKWCostInput, app.perKWhCostInput];
            threePartInputs = [app.fixedCostInput, app.variableCostInput, ...
                app.semiFixedCostInput];
            
            %    Based on the button that was selected, enable/disable inputs
            if (selectedButton == app.twoPartCostButton)
                %    Enable 2 part cost IPs, disable 3 part cost IPs
                enableInputs(app, twoPartInputs);
                disableInputs(app, threePartInputs);
            else 
                %    Enable 3 part cost IPs, disable 2 part cost IPs
                enableInputs(app, threePartInputs);
                disableInputs(app, twoPartInputs);
            end
        end

        % economicSubmitBtn button pushed function
        function costCalculationBtnPressed(app)
            %    Get active profile name
            activeProfile = app.displayNameToVariable(app.dropDown.Value);
            updateEconomicMetrics(app, activeProfile);
        end

        % comparisonButtonGroup selection change function
        function comparisonTypeChanged(app, event)
            selectedButton = app.comparisonButtonGroup.SelectedObject;
            
            %    Update Axes based on selected plot
            switch(selectedButton.Text)
                case 'Load Factor'
                    generateComparisonPlot(app, computeAllLoadFactors(app), 'Load Factor');
                    app.descProfileNameLabel.Text = 'Load Factor';
                case 'Overall Cost/kWh'
                    generateComparisonPlot(app, computeAllPerUnitCosts(app), 'Overall Cost/kWh');
                    app.descProfileNameLabel.Text = 'Overall Cost/kWh';
            end
        end

        % comparisonSubmitBtn button pushed function
        function comparisonSubmitBtnPushed(app)
            updateComparisonMetrics(app);
            TabGroupSelectionChanged(app);
            generateComparisonPlot(app, computeAllPerUnitCosts(app), 'Overall Cost/kWh');
        end

        % TabGroup selection changed function
        function TabGroupSelectionChanged(app)
            disp('Tab selection callback triggered');
            selectedTab = app.TabGroup.SelectedTab;

            if (strcmp(selectedTab.Title,'Comparison'))
                %    Display load factors comparison plot by default
                generateComparisonPlot(app, computeAllLoadFactors(app), 'Load Factor');

                %    Update best/worst results in panel                
                updateComparisonMetrics(app);
                
                %    Disable dropdown menu - user can't trigger new load profile graph
                app.dropDown.Enable = 'off';
                
                %    Hide the reference button
                app.referenceButton.Visible = 'off';
                
                %    Clear all panels
                app.descriptionTextArea.Value = importdata('./Descriptions/Comparison.txt');
                fclose('all');
                
                app.recommendationTextArea.Value = importdata('./Recommendations/Comparison.txt');
                fclose('all');
                
                %    Rename key areas
                app.descriptionPanel.Title = 'Comparison';
                app.descProfileNameLabel.Text = 'Load Factor';                
            else
                %    Enable the dropdown menu for selection 
                app.dropDown.Enable = 'on';
                
                %    Show the reference button
                app.referenceButton.Visible = 'on';
              
                %    Get the last active load profile from dropdown
                lastActiveLoadProfile = app.displayNameToVariable(app.dropDown.Value);
                
                %    Change the panel title
                app.descriptionPanel.Title = 'Load Profile Description';
                
                %    Update panels and metrics and load profile
                updateLoadProfileGraph(app, lastActiveLoadProfile);
                updateTechnicalMetrics(app, lastActiveLoadProfile);
                updateEconomicMetrics(app, lastActiveLoadProfile);
                updateRecommendationsPanel(app, lastActiveLoadProfile);
                updateDescriptionPanel(app, lastActiveLoadProfile);
            end
        end

        % modelButton button pushed function
        function modelBtnPressed(app)
            %    Get the name of the current load profile
            profileName = displayNameToVariable(app, ...
                app.profileNameLabel.Text);
            
            % Build URL filepath
            disp(profileName);
            filepath = strcat('./Links/Models/', profileName, '.txt');
            disp(filepath);
            
            % Extract URL from the file
            URL = importdata(filepath);
            
            %    Remember to close the file before proceeding
            fclose('all');
          
            %    Open with MATLAB's `webpage` command - must cast to char
            web(char(URL), '-browser');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Load Profile Analysis App';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.profileNameLabel = uilabel(app.UIFigure);
            app.profileNameLabel.Text = 'GraphTitle';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.technicalTab = uitab(app.TabGroup);
            app.technicalTab.Units = 'pixels';
            app.technicalTab.Title = 'Technical Analysis';
            app.LabelNumericEditField2 = uilabel(app.technicalTab);
            app.LabelNumericEditField2.Text = 'Peak Load (kW)';
            app.peakLoadField = uieditfield(app.technicalTab, 'numeric');
            app.peakLoadField.ValueDisplayFormat = '%5.2f';
            app.peakLoadField.Editable = 'off';
            app.baseLoadLabel = uilabel(app.technicalTab);
            app.baseLoadLabel.Text = 'Base Load (kW)';
            app.baseLoadField = uieditfield(app.technicalTab, 'numeric');
            app.baseLoadField.ValueDisplayFormat = '%5.2f';
            app.baseLoadField.Editable = 'off';
            app.avgLoadLabel = uilabel(app.technicalTab);
            app.avgLoadLabel.Text = 'Avg Load (kW)';
            app.avgLoadField = uieditfield(app.technicalTab, 'numeric');
            app.avgLoadField.Limits = [0 Inf];
            app.avgLoadField.ValueDisplayFormat = '%5.2f';
            app.avgLoadField.Editable = 'off';
            app.unitsLabel = uilabel(app.technicalTab);
            app.unitsLabel.Text = 'Units (kWh)';
            app.unitsField = uieditfield(app.technicalTab, 'numeric');
            app.unitsField.ValueDisplayFormat = '%5.2f';
            app.unitsField.Editable = 'off';
            app.actualLoadFactorLabel = uilabel(app.technicalTab);
            app.actualLoadFactorLabel.Text = 'Actual Load Factor';
            app.actualLoadFactorField = uieditfield(app.technicalTab, 'numeric');
            app.actualLoadFactorField.ValueDisplayFormat = '%5.2f';
            app.actualLoadFactorField.Editable = 'off';
            app.idealLoadFactorLabel = uilabel(app.technicalTab);
            app.idealLoadFactorLabel.Text = 'Target Load Factor';
            app.targetLoadFactorField = uieditfield(app.technicalTab, 'numeric');
            app.targetLoadFactorField.ValueChangedFcn = createCallbackFcn(app, @targetLoadFactorFieldValueChanged);
            app.targetLoadFactorField.Limits = [0 1];
            app.targetLoadFactorField.ValueDisplayFormat = '%5.2f';
            app.targetLoadFactorField.Value = 0.5;
            app.economicTab = uitab(app.TabGroup);
            app.economicTab.Units = 'pixels';
            app.economicTab.Title = 'Economic Analysis';
            app.CostTypeButtonGroup = uibuttongroup(app.economicTab);
            app.CostTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @costTypeChanged, true);
            app.CostTypeButtonGroup.Title = 'Cost Type';
            app.CostTypeButtonGroup.Units = 'pixels';
            app.twoPartCostButton = uiradiobutton(app.CostTypeButtonGroup);
            app.twoPartCostButton.Text = 'Two-Part';
            app.twoPartCostButton.Value = true;
            app.threePartCostButton = uiradiobutton(app.CostTypeButtonGroup);
            app.threePartCostButton.Text = 'Three-Part';
            app.perKWLabel = uilabel(app.economicTab);
            app.perKWLabel.Text = 'Per kW (PKR)';
            app.perKWCostInput = uieditfield(app.economicTab, 'numeric');
            app.perKWCostInput.Limits = [0 Inf];
            app.perKWCostInput.ValueDisplayFormat = '%5.2f';
            app.perKWCostInput.Value = 5;
            app.perKWhLabel = uilabel(app.economicTab);
            app.perKWhLabel.Text = 'Per kWh (PKR)';
            app.perKWhCostInput = uieditfield(app.economicTab, 'numeric');
            app.perKWhCostInput.ValueDisplayFormat = '%5.2f';
            app.perKWhCostInput.Value = 5;
            app.fixedCostLabel = uilabel(app.economicTab);
            app.fixedCostLabel.Text = 'Fixed (PKR)';
            app.fixedCostInput = uieditfield(app.economicTab, 'numeric');
            app.fixedCostInput.ValueDisplayFormat = '%5.2f';
            app.fixedCostInput.Enable = 'off';
            app.fixedCostInput.Value = 5;
            app.semiFixedCostLabel = uilabel(app.economicTab);
            app.semiFixedCostLabel.Enable = 'off';
            app.semiFixedCostLabel.Text = 'Semi Fixed (PKR)';
            app.semiFixedCostInput = uieditfield(app.economicTab, 'numeric');
            app.semiFixedCostInput.Limits = [0 Inf];
            app.semiFixedCostInput.ValueDisplayFormat = '%5.2f';
            app.semiFixedCostInput.Enable = 'off';
            app.semiFixedCostInput.Value = 5;
            app.variableCostLabel = uilabel(app.economicTab);
            app.variableCostLabel.Enable = 'off';
            app.variableCostLabel.Text = 'Variable (PKR)';
            app.variableCostInput = uieditfield(app.economicTab, 'numeric');
            app.variableCostInput.Limits = [0 Inf];
            app.variableCostInput.ValueDisplayFormat = '%5.2f';
            app.variableCostInput.Enable = 'off';
            app.variableCostInput.Value = 5;
            app.LabelNumericEditField = uilabel(app.economicTab);
            app.LabelNumericEditField.Text = {'Ideal Cost/'; 'kWh (PKR)'};
            app.idealPUCostField = uieditfield(app.economicTab, 'numeric');
            app.idealPUCostField.Limits = [0 9999];
            app.idealPUCostField.ValueDisplayFormat = '%5.2f';
            app.idealPUCostField.Value = 16.3;
            app.actualPUCostLabel = uilabel(app.economicTab);
            app.actualPUCostLabel.Text = {'Actual Cost/'; 'kWh (PKR)'};
            app.actualPUCostField = uieditfield(app.economicTab, 'numeric');
            app.actualPUCostField.Limits = [0 Inf];
            app.actualPUCostField.ValueDisplayFormat = '%5.2f';
            app.actualPUCostField.Editable = 'off';
            app.economicUnitsLabel = uilabel(app.economicTab);
            app.economicUnitsLabel.Text = 'Units (kWh)';
            app.economicUnitsField = uieditfield(app.economicTab, 'numeric');
            app.economicUnitsField.ValueDisplayFormat = '%5.2f';
            app.economicUnitsField.Editable = 'off';
            app.economicSubmitBtn = uibutton(app.economicTab, 'push');
            app.economicSubmitBtn.ButtonPushedFcn = createCallbackFcn(app, @costCalculationBtnPressed);
            app.economicSubmitBtn.Text = 'Update';
            app.comparisonTab = uitab(app.TabGroup);
            app.comparisonTab.Units = 'pixels';
            app.comparisonTab.Title = 'Comparison';
            app.comparisonButtonGroup = uibuttongroup(app.comparisonTab);
            app.comparisonButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @comparisonTypeChanged, true);
            app.comparisonButtonGroup.Title = 'Comparison Type';
            app.comparisonButtonGroup.Units = 'pixels';
            app.loadFactorComparison = uiradiobutton(app.comparisonButtonGroup);
            app.loadFactorComparison.Text = 'Load Factor';
            app.loadFactorComparison.Value = true;
            app.costPerKWhComparison = uiradiobutton(app.comparisonButtonGroup);
            app.costPerKWhComparison.Text = 'Overall Cost/kWh';
            app.comparisonSubmitBtn = uibutton(app.comparisonTab, 'push');
            app.comparisonSubmitBtn.ButtonPushedFcn = createCallbackFcn(app, @comparisonSubmitBtnPushed);
            app.comparisonSubmitBtn.Text = 'Update';
            app.comparisonKWhLabel = uilabel(app.comparisonTab);
            app.comparisonKWhLabel.Text = 'PKR/kWh';
            app.comparisonKWhInput = uieditfield(app.comparisonTab, 'numeric');
            app.comparisonKWhInput.ValueDisplayFormat = '%5.2f';
            app.comparisonKWhInput.Value = 5;
            app.comparisonKWLabel = uilabel(app.comparisonTab);
            app.comparisonKWLabel.Text = 'PKR/kW';
            app.comparisonKWInput = uieditfield(app.comparisonTab, 'numeric');
            app.comparisonKWInput.ValueDisplayFormat = '%5.2f';
            app.comparisonKWInput.Value = 5;
            app.bestLFNameLabel = uilabel(app.comparisonTab);
            app.bestLFNameLabel.Text = 'Load Name';
            app.bestLFName = uieditfield(app.comparisonTab, 'text');
            app.bestLFName.Editable = 'off';
            app.bestLFValueLabel = uilabel(app.comparisonTab);
            app.bestLFValueLabel.Text = 'Best Load Factor';
            app.bestLFValue = uieditfield(app.comparisonTab, 'numeric');
            app.bestLFValue.ValueDisplayFormat = '%5.2f';
            app.bestLFValue.Editable = 'off';
            app.bestCostNameLabel = uilabel(app.comparisonTab);
            app.bestCostNameLabel.Text = 'Load Name';
            app.bestCostName = uieditfield(app.comparisonTab, 'text');
            app.bestCostName.Editable = 'off';
            app.bestCostValueLabel = uilabel(app.comparisonTab);
            app.bestCostValueLabel.Text = 'Best Overall Cost';
            app.bestCostValue = uieditfield(app.comparisonTab, 'numeric');
            app.bestCostValue.ValueDisplayFormat = '%5.2f';
            app.bestCostValue.Editable = 'off';
            app.Label = uilabel(app.comparisonTab);
            app.Label.Text = 'Comparison Input';
            app.Label2 = uilabel(app.comparisonTab);
            app.Label2.Text = 'Comparison Results';
            app.dropDownLabel = uilabel(app.UIFigure);
            app.dropDownLabel.Text = 'Select Load Profile';
            app.dropDown = uidropdown(app.UIFigure);
            app.dropDown.ValueChangedFcn = createCallbackFcn(app, @dropDownValueChanged, true);
            app.descriptionPanel = uipanel(app.UIFigure);
            app.descriptionPanel.Title = 'Load Profile Description';
            app.descriptionPanel.Units = 'pixels';
            app.descProfileNameLabel = uilabel(app.descriptionPanel);
            app.descProfileNameLabel.Text = 'Profile Name';
            app.descriptionTextArea = uitextarea(app.descriptionPanel);
            app.descriptionTextArea.Editable = 'off';
            app.referenceButton = uibutton(app.descriptionPanel, 'push');
            app.referenceButton.ButtonPushedFcn = createCallbackFcn(app, @referenceButtonPressed);
            app.referenceButton.Text = 'Reference';
            app.modelButton = uibutton(app.descriptionPanel, 'push');
            app.modelButton.ButtonPushedFcn = createCallbackFcn(app, @modelBtnPressed);
            app.modelButton.Enable = 'off';
            app.modelButton.Text = 'Model';
            app.recommendationsPanel = uipanel(app.UIFigure);
            app.recommendationsPanel.Title = 'Recommendations';
            app.recommendationsPanel.Units = 'pixels';
            app.recommendationTextArea = uitextarea(app.recommendationsPanel);
            app.recommendationTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PG_App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end